classdef viewer4Dflow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        viewer4DflowUIFigure      matlab.ui.Figure
        ExportGIF                 matlab.ui.control.Button
        RotateCoronal             matlab.ui.control.Button
        RotateSagittal            matlab.ui.control.Button
        RotateAxial               matlab.ui.control.Button
        FlipCoronal               matlab.ui.control.Button
        FlipSagittal              matlab.ui.control.Button
        FlipAxial                 matlab.ui.control.Button
        SagittalSlider            matlab.ui.control.Slider
        CoronalSlider             matlab.ui.control.Slider
        AxialSlider               matlab.ui.control.Slider
        TextArea                  matlab.ui.control.TextArea
        SagittalLabel             matlab.ui.control.Label
        AxialLabel                matlab.ui.control.Label
        CoronalLabel              matlab.ui.control.Label
        IntervalEditField         matlab.ui.control.NumericEditField
        IntervalmsEditFieldLabel  matlab.ui.control.Label
        PlayButton                matlab.ui.control.Button
        AutoWLButton              matlab.ui.control.Button
        TimeResButtonGroup        matlab.ui.container.ButtonGroup
        TimeResolvedButton        matlab.ui.control.RadioButton
        TimeAveragedButton        matlab.ui.control.RadioButton
        EncodeDropDown            matlab.ui.control.DropDown
        EncodeDropDownLabel       matlab.ui.control.Label
        LoadDataButton            matlab.ui.control.Button
        WindowEditField           matlab.ui.control.NumericEditField
        TimeSlider                matlab.ui.control.Slider
        TimeFrameLabel            matlab.ui.control.Label
        WindowLabel               matlab.ui.control.Label
        LevelEditField            matlab.ui.control.NumericEditField
        LevelLabel                matlab.ui.control.Label
        axAxial                   matlab.ui.control.UIAxes
        axCoronal                 matlab.ui.control.UIAxes
        axSagittal                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        dataLoaded = false;
        struct_loaded = false;
        imageData
        currData
        currEncode = "MAG"
        currTime = "AVG";
        sagittalSlice = 1;
        coronalSlice = 1;
        axialSlice = 1;
        frames
        tframe = 1;
        xres
        yres
        zres
        clims
        window
        level
        flipX = false;
        flipY = false;
        flipZ = false;
        rotX = 0;
        rotY = 0;
        rotZ = 0;
        playTimer
        tinterval = 100;
        mag_vel_switch = false;
        encode_list = [];
    end
    
    methods (Access = private)

        function load_rad(app, filepath)
            fid = fopen(fullfile(filepath,'pcvipr_header.txt'), 'r'); %open header
            dataArray = textscan(fid,'%s%s%[^\n\r]','Delimiter',' ', ...
                'MultipleDelimsAsOne',true,'ReturnOnError',false); %parse header info
            fclose(fid);
            dataArray{1,2} = cellfun(@str2num,dataArray{1,2}(:),'UniformOutput',false);
            pcviprHeader = cell2struct(dataArray{1,2}(:),dataArray{1,1}(:),1); %turn to structure
            resx = pcviprHeader.matrixx; %resolution in x
            resy = pcviprHeader.matrixy; %resolution in y
            resz = pcviprHeader.matrixz; %resolution in z
            nframes = pcviprHeader.frames; %number of cardiac frames
            
            MAG = load_dat(app, fullfile(filepath,'MAG.dat'),[resx resy resz]); % Average magnitude
            app.encode_list = [app.encode_list "Magnitude"];
            log(app, 'Loading time averaged data...');
            try
                CD = load_dat(app, fullfile(filepath,'CD.dat'),[resx resy resz]); % Average complex difference
                app.encode_list = [app.encode_list "Complex Difference"];
            catch
                disp("No Complex Difference data found")
            end
            try
                VEL1 = load_dat(app, fullfile(filepath,'comp_vd_1.dat'),[resx resy resz]); % Average velocity
                app.encode_list = [app.encode_list "Velocity 1"];
            catch
                disp("No Velocity 1 data found")
            end
            try
                VEL2 = load_dat(app, fullfile(filepath,'comp_vd_2.dat'),[resx resy resz]);
                app.encode_list = [app.encode_list "Velocity 2"];
            catch
                disp("No Velocity 2 data found")
            end
            try
                VEL3 = load_dat(app, fullfile(filepath,'comp_vd_3.dat'),[resx resy resz]); 
                app.encode_list = [app.encode_list "Velocity 3"];
            catch
                disp("No Velocity 3 data found")
            end
            
            app.imageData.header = pcviprHeader;
            app.imageData.MAG = MAG;
            app.imageData.CD = CD;
            app.imageData.VEL1 = VEL1;
            app.imageData.VEL2 = VEL2;
            app.imageData.VEL3 = VEL3;

            if nframes >1 
                % Initialize data time-resolved data arrays
                mag = zeros(resx,resy,resz, nframes); % Time-resolved magnitude
                cd = zeros(resx,resy,resz, nframes); % Time-resolved complex difference
                v1 = zeros(resx,resy,resz, nframes); % Time-resolved velocity 
                v2 = zeros(resx,resy,resz, nframes); 
                v3 = zeros(resx,resy,resz, nframes);
                log(app, sprintf('Loading time frame 1/%d...', nframes));
                for j = 1:nframes
                    if j ~= 1
                        updateLog(app, sprintf('Loading time frame %d/%d...', j, nframes));
                    end
                    mag(:,:,:,j) = load_dat(app, fullfile(filepath,[filesep 'ph_' num2str(j-1,'%03i') '_mag.dat']), [resx resy resz]);
                    if any(strcmp("Complex Difference", app.encode_list))
                        cd(:,:,:,j) = load_dat(app, fullfile(filepath,[filesep 'ph_' num2str(j-1,'%03i') '_cd.dat']), [resx resy resz]);
                    end
                    if any(strcmp("Velocity 1", app.encode_list))
                        v1(:,:,:,j) = load_dat(app, fullfile(filepath,[filesep 'ph_' num2str(j-1,'%03i') '_vd_1.dat']), [resx resy resz]);
                    end
                    if any(strcmp("Velocity 2", app.encode_list))
                        v2(:,:,:,j) = load_dat(app, fullfile(filepath,[filesep 'ph_' num2str(j-1,'%03i') '_vd_2.dat']), [resx resy resz]);
                    end
                    if any(strcmp("Velocity 3", app.encode_list))
                        v3(:,:,:,j) = load_dat(app, fullfile(filepath,[filesep 'ph_' num2str(j-1,'%03i') '_vd_3.dat']), [resx resy resz]);
                    end
                end
                app.imageData.mag = mag;
                app.imageData.cd = cd;
                app.imageData.vel1 = v1;
                app.imageData.vel2 = v2;
                app.imageData.vel3 = v3;
            end
        end

        % function load_h5(app, filepath)
        % 
        % end

        % function load_dcm(app, filepath)
        % 
        % end
        
        function handleScroll(app, event)
            % Get current mouse position
            mousePos = app.viewer4DflowUIFigure.CurrentPoint;
            axHandles = [app.axAxial, app.axCoronal, app.axSagittal];
        
            % Scroll direction
            delta = event.VerticalScrollCount;
        
            % Determine which axis the mouse is over
            for k = 1:numel(axHandles)
                ax = axHandles(k);
                pos = ax.Position;
                if mousePos(1) >= pos(1) && mousePos(1) <= pos(1) + pos(3) && ...
                   mousePos(2) >= pos(2) && mousePos(2) <= pos(2) + pos(4)
        
                    switch ax
                        case app.axSagittal
                            app.sagittalSlice = clamp(app, app.sagittalSlice - delta, [1 app.xres]);
                            app.SagittalSlider.Value = app.sagittalSlice;
                        case app.axCoronal
                            app.coronalSlice = clamp(app, app.coronalSlice - delta, [1 app.yres]);
                            app.CoronalSlider.Value = app.coronalSlice;
                        case app.axAxial
                            app.axialSlice= clamp(app, app.axialSlice - delta, [1 app.zres]);
                            app.AxialSlider.Value = app.axialSlice;
                    end
                    updateViews(app);
                    return
                end
            end
        end

        function rotate_data(app, axis)
            fields = fieldnames(app.imageData);
            for idx = 1:length(fields)
                vol = app.imageData.(fields{idx});
                if ~isnumeric(vol)
                    continue
                end
                if ndims(vol) == 3
                    switch axis
                        case 1 % X
                            rotated = permute(vol, [1 3 2]);
                            rotated = flip(rotated, 3);
                        case 2 % Y
                            rotated = permute(vol, [3 2 1]);
                            rotated = flip(rotated, 1);
                        case 3 % Z
                            rotated = permute(vol, [2 1 3]);
                            rotated = flip(rotated, 1);
                    end
                else
                    switch axis
                        case 1 % X
                            rotated = permute(vol, [1 3 2 4]);
                            rotated = flip(rotated, 3);
                        case 2 % Y
                            rotated = permute(vol, [3 2 1 4]);
                            rotated = flip(rotated, 1);
                        case 3 % Z
                            rotated = permute(vol, [2 1 3 4]);
                            rotated = flip(rotated, 1);
                    end
                end
                app.imageData.(fields{idx}) = rotated;
                switch axis
                    case 1 % X-axis: swap Y and Z
                        [app.yres, app.zres] = deal(app.zres, app.yres);
                        [app.coronalSlice, app.axialSlice] = deal(app.axialSlice, app.coronalSlice);
                    case 2 % Y-axis: swap X and Z
                        [app.xres, app.zres] = deal(app.zres, app.xres);
                        [app.sagittalSlice, app.axialSlice] = deal(app.axialSlice, app.sagittalSlice);
                    case 3 % Z-axis: swap X and Y
                        [app.xres, app.yres] = deal(app.yres, app.xres);
                        [app.sagittalSlice, app.coronalSlice] = deal(app.coronalSlice, app.sagittalSlice);
                end
            end
        end

        function flip_data(app, axis)
            fields = fieldnames(app.imageData);
            for idx = 1:length(fields)
                vol = app.imageData.(fields{idx});
                if ~isnumeric(vol)
                    continue
                end
                app.imageData.(fields{idx}) = flip(vol, axis);
            end
        end

        function pack_data(app, data)
            dims = size(data);
            switch ndims(data)
                case 3
                    app.imageData.MAG = data;
                    app.imageData.header.matrixx = dims(1);
                    app.imageData.header.matrixy = dims(2);
                    app.imageData.header.matrixz = dims(3);
                    app.imageData.header.frames = 1;
                case 4
                    app.imageData.mag = data;
                    app.imageData.MAG = mean(data, 4);
                    app.imageData.header.matrixx = dims(1);
                    app.imageData.header.matrixy = dims(2);
                    app.imageData.header.matrixz = dims(3);
                    app.imageData.header.frames = dims(4);
            end
        end

        function export_gif(app, view, filename)
            h = figure('Visible', 'off', 'Color', 'white', 'MenuBar', 'none', 'ToolBar', 'none');
            axh = axes('Parent', h, 'Position', [0 0 1 1]); % Fill the figure with the axes
            vol = app.currData;
            for k = 1:app.frames
                switch view
                    case 1
                        vol_img = squeeze(vol(app.sagittalSlice, :, :, k));
                    case 2
                        vol_img = squeeze(vol(:, app.coronalSlice, :, k));
                    case 3
                        vol_img = squeeze(vol(:, :, app.axialSlice, k));
                end
                imshow(vol_img, app.clims, 'Parent', axh, 'InitialMagnification', 100);
                axis off tight
            
                % Capture the plot as an image
                frame = getframe(axh);
                img = frame2im(frame);
                [imind, cm] = rgb2ind(img, 256);
            
                % Write to gif
                if k == 1
                    imwrite(imind, cm, filename, 'gif', 'Loopcount', inf, 'DelayTime', app.tinterval/1000);
                else
                    imwrite(imind, cm, filename, 'gif', 'WriteMode', 'append', 'DelayTime', app.tinterval/1000);
                end
            end
            close(h);
        end

        function autoWL(app)
            if app.currEncode == "VEL1" || app.currEncode == "VEL2" || app.currEncode == "VEL3"
                app.WindowEditField.Limits = [-inf, inf];
                app.LevelEditField.Limits = [-inf, inf];
            else
                app.WindowEditField.Limits = [0, inf];
                app.LevelEditField.Limits = [0, inf];
            end
            dataMax = double(max(app.currData(:)));
            dataMin = double(min(app.currData(:)));
            win = double(dataMax-dataMin);
            win (win < 1) = 1;
            if win == 0
                win = 1;
            end
            lev = double(dataMin) + (win/2);
            app.WindowEditField.Value = win;
            app.LevelEditField.Value = lev;
            app.window = win;
            app.level = lev;
            updateWL(app)
        end

        function updateWL(app)
            app.clims = [(app.level - app.window/2) (app.level + app.window/2)];         
            app.axSagittal.CLim = app.clims;
            app.axCoronal.CLim = app.clims;       
            app.axAxial.CLim = app.clims;
        end

        function playFrame(app)
            currentT = app.tframe;
            nextT = mod(currentT, app.frames) + 1;
            app.tframe = nextT;
            app.TimeSlider.Value = nextT;
        
            % Update images
            updateViews(app);  % or whatever you use to redraw the frame
        end

        function log(app, msg)
            val = app.TextArea.Value;
            if isempty(val)
                val = {msg};
            else
                val{end+1} = msg;
            end
            app.TextArea.Value = val;
            drawnow;
            scroll(app.TextArea, 'bottom')
        end

        function updateLog(app, msg)
            val = app.TextArea.Value;
            if isempty(val)
                val = {msg};
            else
                val{end} = msg;  % replace last line
            end
            app.TextArea.Value = val;
            drawnow;
            scroll(app.TextArea, 'bottom')
        end

        function v = load_dat(~, filename, res)
            m = memmapfile(filename, 'Format', {'int16', prod(res), 'data'});
            v = single(reshape(m.Data.data, res));
        end

        function v = clamp(~, val, limits)
            v = max(min(val, limits(2)), limits(1));
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            app.WindowEditField.Enable = "off";
            app.LevelEditField.Enable = "off";
            app.EncodeDropDown.Enable = "off";
            app.TimeResButtonGroup.Enable = "off";
            app.AxialSlider.Enable = "off";
            app.CoronalSlider.Enable = "off";
            app.SagittalSlider.Enable = "off";
            app.TimeSlider.Enable = "off";
            app.IntervalEditField.Enable = "off";
            app.PlayButton.Enable = "off";

            narginchk(1,2);
            if nargin == 2
                data = varargin{1};
                if isstruct(data)
                    if isfield(data, "header")
                        app.imageData = data;
                    else
                        error("Data not formatted correctly")
                    end
                elseif numel(size(data))>2
                    pack_data(app, data)
                else
                    error("Unrecognized input data type")
                end
                app.struct_loaded = true;
                LoadDataButtonPushed(app, []);
            end
            app.viewer4DflowUIFigure.WindowScrollWheelFcn = @(src, event) app.handleScroll(event);
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            if app.dataLoaded
                cla(app.imageData);
                cla(app.axAxial);
                cla(app.axCoronal);
                cla(app.axSagittal);
                cla(app.xres);
                cla(app.yres);
                cla(app.zres);
                cla(app.frames);
                app.sagittalSlice = 1;
                app.coronalSlice = 1;
                app.axialSlice = 1;
                app.tframe = 1;
                app.flipX = false;
                app.flipY = false;
                app.flipZ = false;
                app.permute_order = [1; 2; 3];
                app.rotX = 0;
                app.rotY = 0;
                app.rotZ = 0;
                app.TimeResButtonGroup.SelectedObject = app.TimeAveragedButton;
                app.EncodeDropDown.Value = "Magnitude";
                app.currEncode = "MAG";
                app.currTime = "AVG";
                app.encode_list = [];
                app.axialSlider.Value = 1;
                app.coronalSlider.Value = 1;
                app.SagittalSlider.Value = 1;
                app.TimeSlider.Value = 1;
            end

            if app.struct_loaded
                app.dataLoaded = true;
                if isfield(app.imageData, "MAG")
                    app.encode_list = [app.encode_list "Magnitude"];
                end
                if isfield(app.imageData, "CD")
                    app.encode_list = [app.encode_list "Complex Difference"];
                end
                if isfield(app.imageData, "VEL1")
                    app.encode_list = [app.encode_list "Velocity 1"];
                end
                if isfield(app.imageData, "VEL2")
                    app.encode_list = [app.encode_list "Velocity 2"];
                end
                if isfield(app.imageData, "VEL3")
                    app.encode_list = [app.encode_list "Velocity 3"];
                end
            else
                [data_file, filepath] = uigetfile({'*.txt'; '*.h5'; '*.dcm'});
                [~,fname,fext] = fileparts(data_file);
                switch lower(fext)
                    case ".txt"
                        if fname == "pcvipr_header"
                            load_rad(app, filepath);
                        end
                    case ".h5"
                        load_h5(fullfile(app, filepath, data_file));
                    case ".dcm"
                        load_dcm(app, filepath);
                end
            end

            app.xres = app.imageData.header.matrixx;
            app.yres = app.imageData.header.matrixy;
            app.zres = app.imageData.header.matrixz;
            app.frames = app.imageData.header.frames;
            
            app.WindowEditField.Enable = "on";
            app.LevelEditField.Enable = "on";
            app.EncodeDropDown.Enable = "on";
            if app.frames > 1
                app.TimeResButtonGroup.Enable = "on";
            end
            app.AxialSlider.Enable = "on";
            app.CoronalSlider.Enable = "on";
            app.SagittalSlider.Enable = "on";
            app.TimeSlider.Enable = "off";
            app.IntervalEditField.Enable = "off";
            app.PlayButton.Enable = "off";
            app.ExportGIF.Enable = "off";
            app.EncodeDropDown.Items = app.encode_list;

            % rotation matrix
            if isfield(app.imageData.header, "ix")
                rot_matrix = [app.imageData.header.ix app.imageData.header.iy app.imageData.header.iz;...
                         app.imageData.header.jx app.imageData.header.jy app.imageData.header.jz;...
                         app.imageData.header.kx app.imageData.header.ky app.imageData.header.kz];
                
                [~, max_indices] = max(abs(rot_matrix), [], 2);
                signs = sign(diag(rot_matrix(1:3, max_indices)));

                fields = fieldnames(app.imageData);
                for idx = 1:length(fields)
                    if ~isnumeric(app.imageData.(fields{idx}))
                        continue
                    end
                    vol = app.imageData.(fields{idx});
                    if isequal(max_indices, [2; 1; 3])
                        permute_order = [2, 1, 3];
                        [app.xres, app.yres] = deal(app.yres, app.xres);
                    elseif isequal(max_indices, [3; 2; 1])
                        permute_order = [3, 2, 1];
                        [app.xres, app.zres] = deal(app.zres, app.xres);
                    elseif isequal(max_indices, [1; 3; 2])
                        permute_order = [1, 3, 2];
                        [app.yres, app.zres] = deal(app.zres, app.yres);
                    else
                        permute_order = [1, 2, 3];
                    end
                    if ndims(vol) == 3
                        vol = permute(vol, permute_order);
                    else
                        vol = permute(vol, [permute_order 4]);
                    end
                    if signs(1) < 0
                        app.flipX = true;
                        vol = flip(vol, 1);
                    end
                    if signs(2) < 0
                        app.flipY = true;
                        vol = flip(vol, 2);
                    end
                    if signs(3) < 0
                        app.flipZ = true;
                        vol = flip(vol, 3);
                    end
                    app.imageData.(fields{idx}) = vol;
                end
            end

            app.currData = app.imageData.MAG;
            
            app.sagittalSlice = round(size(app.imageData.MAG, 3)/2);
            app.SagittalSlider.Value = round(size(app.imageData.MAG, 1)/2);
            app.SagittalSlider.Limits = [1 app.xres];
            app.coronalSlice = round(size(app.imageData.MAG, 2)/2);
            app.CoronalSlider.Value = round(size(app.imageData.MAG, 2)/2);
            app.CoronalSlider.Limits = [1 app.yres];
            if app.zres == 1
                app.AxialSlider.Value = 1;
                app.axialSlice = 1;
                app.AxialSlider.Limits = [1 2];
            else
                app.AxialSlider.Value = round(size(app.imageData.MAG, 3)/2);
                app.axialSlice = round(size(app.imageData.MAG, 1)/2);
                app.AxialSlider.Limits = [1 app.zres];
            end
            
            if app.frames == 1
                app.TimeSlider.Limits = [1, 2];
                app.TimeSlider.MajorTicks = 1:2;
            else
                app.TimeSlider.Limits = [1, app.imageData.header.frames];
                app.TimeSlider.MajorTicks = 1:app.imageData.header.frames;
            end
            app.TimeSlider.MinorTicks = [];

            log(app, 'Data loaded');

            autoWL(app);
            updateViews(app);
        end

        % Callback function: TimeSlider, axAxial, axCoronal, axSagittal
        function updateViews(app, event)
            switch app.currTime
                case "AVG"
                    switch app.currEncode
                        case "MAG"
                            app.currData = app.imageData.MAG;
                        case "CD"
                            app.currData = app.imageData.CD;
                        case "VEL1"
                            app.currData = app.imageData.VEL1;
                        case "VEL2"
                            app.currData = app.imageData.VEL2;
                        case "VEL3"
                            app.currData = app.imageData.VEL3;
                    end
                case "TRES"
                    switch app.currEncode
                        case "MAG"
                            app.currData = app.imageData.mag;
                        case "CD"
                            app.currData = app.imageData.cd;
                        case "VEL1"
                            app.currData = app.imageData.vel1;
                        case "VEL2"
                            app.currData = app.imageData.vel2;
                        case "VEL3"
                            app.currData = app.imageData.vel3;
                    end
            end
            
            if app.currTime == "TRES"
                t = app.tframe;
            else
                t = 1;
            end
            
            % Extract views
            axial = squeeze(app.currData(:, :, app.axialSlice, t));
            coronal = squeeze(app.currData(:, app.coronalSlice, :, t));
            sagittal = squeeze(app.currData(app.sagittalSlice, :, :, t));
            
            % Axial
            imagesc(app.axAxial, axial);
            colormap(app.axAxial, gray);

            app.AxialLabel.Text = sprintf('%d/%d', ...
                app.axialSlice, app.zres);

            % Coronal
            imagesc(app.axCoronal, coronal);
            colormap(app.axCoronal, gray);

            app.CoronalLabel.Text = sprintf('%d/%d', ...
                app.coronalSlice, app.yres);
        
            % Sagittal
            imagesc(app.axSagittal, sagittal);
            colormap(app.axSagittal, gray);

            app.SagittalLabel.Text = sprintf('%d/%d', ...
                app.sagittalSlice, app.xres);

            if app.mag_vel_switch
                autoWL(app);
                app.mag_vel_switch = false;
            end
        end

        % Value changed function: EncodeDropDown
        function EncodeDropDownValueChanged(app, event)
            prev_encode = app.currEncode;
            value = app.EncodeDropDown.Value;
            switch value
                case "Magnitude"
                    app.currEncode = "MAG";
                case "Complex Difference"
                    app.currEncode = "CD";
                case "Velocity 1"
                    app.currEncode = "VEL1";
                case "Velocity 2"
                    app.currEncode = "VEL2";
                case "Velocity 3"
                    app.currEncode = "VEL3";
            end
            if prev_encode == "MAG" || prev_encode == "CD" 
                if app.currEncode == "VEL1" || app.currEncode == "VEL2" || app.currEncode == "VEL3"
                    app.mag_vel_switch = 1;
                end
            elseif prev_encode == "VEL1" || prev_encode == "VEL2" || prev_encode == "VEL3"
                if app.currEncode == "MAG" || app.currEncode == "CD"
                    app.mag_vel_switch = 1;
                end
            end
            updateViews(app);
        end

        % Selection changed function: TimeResButtonGroup
        function TimeResButtonGroupSelectionChanged(app, event)
            selectedButton = app.TimeResButtonGroup.SelectedObject.Text;
            switch selectedButton
                case "Time Averaged"
                    app.currTime = "AVG";
                    app.TimeSlider.Value = 1;
                    app.TimeSlider.Enable = "off";
                    app.IntervalEditField.Enable = "off";
                    app.ExportGIF.Enable = "off";
                    app.PlayButton.Enable = "off";
                    if ~isempty(app.playTimer) && isvalid(app.playTimer)
                        if strcmp(app.playTimer.Running, 'on')
                            stop(app.playTimer);
                            delete(app.playTimer);
                            app.PlayButton.Text = '▶';
                        end
                    end
                case "Time Resolved"
                    app.currTime = "TRES";
                    app.ExportGIF.Enable = "on";
                    app.TimeSlider.Enable = "on";
                    app.IntervalEditField.Enable = "on";
                    app.PlayButton.Enable = "on";
            end
            updateViews(app);
        end

        % Value changing function: TimeSlider
        function TimeSliderValueChanging(app, event)
            app.tframe = clamp(app, round(event.Value), [1, app.frames]);
            updateViews(app);
            drawnow limitrate
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            if isempty(app.playTimer) || ~isvalid(app.playTimer)
                % Create a new timer
                app.playTimer = timer(...
                    'ExecutionMode', 'fixedRate', ...
                    'Period', (app.tinterval/1000), ...          % Adjust speed here (0.1 = 10 FPS)
                    'TimerFcn', @(~,~) playFrame(app), ...
                    'BusyMode', 'drop');        % Skip frames if overloaded
            end
        
            if strcmp(app.playTimer.Running, 'off')
                start(app.playTimer);
                app.PlayButton.Text = '⏸';
            else
                stop(app.playTimer);
                delete(app.playTimer);
                app.PlayButton.Text = '▶';
            end
        end

        % Value changed function: IntervalEditField
        function IntervalEditFieldValueChanged(app, event)
            app.tinterval = app.IntervalEditField.Value;

            if app.tinterval <= 0
                uialert(app.viewer4DflowUIFigure, 'Time interval must be positive.', 'Invalid Input');
                return
            end
        
            % If the timer exists and is valid
            if ~isempty(app.playTimer) && isvalid(app.playTimer)
                wasRunning = strcmp(app.playTimer.Running, 'on');
                
                % Stop timer if running
                if wasRunning
                    stop(app.playTimer);
                    delete(app.playTimer);
                    app.playTimer = timer(...
                    'ExecutionMode', 'fixedRate', ...
                    'Period', (app.tinterval/1000), ...          % Adjust speed here (0.1 = 10 FPS)
                    'TimerFcn', @(~,~) playFrame(app), ...
                    'BusyMode', 'drop');
                    start(app.playTimer);
                end
            end
        end

        % Value changed function: WindowEditField
        function WindowEditFieldValueChanged(app, event)
            app.window = app.WindowEditField.Value;
            updateWL(app);
        end

        % Value changed function: LevelEditField
        function LevelEditFieldValueChanged(app, event)
            app.level = app.LevelEditField.Value;
            updateWL(app);
        end

        % Button pushed function: AutoWLButton
        function AutoWLButtonPushed(app, event)
            autoWL(app);
        end

        % Button pushed function: FlipAxial
        function FlipAxialButtonPushed(app, event)
            app.flipX = ~app.flipX;
            flip_data(app, 1);
            log(app, 'Flipped Z dimension');
            updateViews(app);
        end

        % Button pushed function: RotateAxial
        function RotateAxialButtonPushed(app, event)
            if app.rotX == 270
                app.rotX = 0;
            else
                app.rotX = app.rotX + 90;
            end
            log(app, 'Rotating 90° around Z...');
            rotate_data(app, 3);
            updateViews(app);
        end

        % Button pushed function: FlipCoronal
        function FlipCoronalButtonPushed(app, event)
            app.flipY = ~app.flipY;
            flip_data(app, 2);
            log(app, 'Flipped Y dimension');
            updateViews(app);
        end

        % Button pushed function: RotateCoronal
        function RotateCoronalButtonPushed(app, event)
            if app.rotY == 270
                app.rotY = 0;
            else
                app.rotY = app.rotY + 90;
            end
            log(app, 'Rotating 90° around Y...');
            rotate_data(app, 2);
            updateViews(app);
        end

        % Button pushed function: FlipSagittal
        function FlipSagittalButtonPushed(app, event)
            app.flipZ = ~app.flipZ;
            flip_data(app, 3);
            log(app, 'Flipped X dimension');
            updateViews(app);
        end

        % Button pushed function: RotateSagittal
        function RotateSagittalButtonPushed(app, event)
            if app.rotZ == 270
                app.rotZ = 0;
            else
                app.rotZ = app.rotZ + 90;
            end
            log(app, 'Rotating 90° around X...');
            rotate_data(app, 1);
            updateViews(app);
        end

        % Value changing function: AxialSlider
        function AxialSliderValueChanging(app, event)
            app.axialSlice = clamp(app, round(event.Value), [1, app.zres]);
            updateViews(app);
            drawnow limitrate
        end

        % Value changing function: CoronalSlider
        function CoronalSliderValueChanging(app, event)
            app.coronalSlice = clamp(app, round(event.Value), [1, app.yres]);
            updateViews(app);
            drawnow limitrate
        end

        % Value changing function: SagittalSlider
        function SagittalSliderValueChanging(app, event)
            app.sagittalSlice = clamp(app, round(event.Value), [1, app.xres]);
            updateViews(app);
            drawnow limitrate
        end

        % Button pushed function: ExportGIF
        function ExportGIFButtonPushed(app, event)
            % Create dialog for checkbox selection
            d = dialog('Position',[300 300 250 200], 'Name','Export views');
        
            % Checkbox controls
            cb1 = uicontrol(d, 'Style','checkbox', 'String','X', ...
                'Position',[100 140 200 20], 'Value',1);
            cb2 = uicontrol(d, 'Style','checkbox', 'String','Y', ...
                'Position',[100 110 200 20], 'Value',1);
            cb3 = uicontrol(d, 'Style','checkbox', 'String','Z', ...
                'Position',[100 80 200 20], 'Value',1);
        
            % OK button
            uicontrol(d, 'Style','pushbutton', 'String','OK', ...
                'Position',[40 20 70 25], ...
                'Callback', @(~,~) uiresume(d));
        
            % Cancel button
            uicontrol(d, 'Style','pushbutton', 'String','Cancel', ...
                'Position',[130 20 70 25], ...
                'Callback', @(~,~) (delete(d)));
        
            uiwait(d);
        
            if ~isvalid(d)
                return; % Cancel pressed
            end
        
            selections = [cb1.Value, cb2.Value, cb3.Value];
            delete(d);
        
            % Ask for save location
            [file, path] = uiputfile('*.gif', 'Save GIF As');
            if isequal(file,0)
                return;
            end
            % Loop through each selected view
            names = ["X", "Y", "Z"];
            parts = split(file, '.');
            for v = 1:3
                if selections(v)
                    gifFile = fullfile(path, strcat(parts{1}, '_view', names(v), '.', parts{2}));
                    export_gif(app, v, gifFile);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.viewer4DflowUIFigure = uifigure('Visible', 'off');
            colormap(app.viewer4DflowUIFigure, 'gray');
            app.viewer4DflowUIFigure.Name = 'viewer4Dflow';
            app.axSagittal = uiaxes(app.viewer4DflowUIFigure);
            app.axSagittal.XTick = [];
            app.axSagittal.YTick = [];
            app.axSagittal.ZTick = [];
            app.axSagittal.ButtonDownFcn = createCallbackFcn(app, @updateViews, true);
            colormap(app.axSagittal, 'gray')
            app.axCoronal = uiaxes(app.viewer4DflowUIFigure);
            app.axCoronal.XTick = [];
            app.axCoronal.YTick = [];
            app.axCoronal.ZTick = [];
            app.axCoronal.ButtonDownFcn = createCallbackFcn(app, @updateViews, true);
            colormap(app.axCoronal, 'gray')
            app.axAxial = uiaxes(app.viewer4DflowUIFigure);
            app.axAxial.XTick = [];
            app.axAxial.YTick = [];
            app.axAxial.ZTick = [];
            app.axAxial.ButtonDownFcn = createCallbackFcn(app, @updateViews, true);
            colormap(app.axAxial, 'gray')
            app.LevelLabel = uilabel(app.viewer4DflowUIFigure);
            app.LevelLabel.Text = 'Level';
            app.LevelEditField = uieditfield(app.viewer4DflowUIFigure, 'numeric');
            app.LevelEditField.Limits = [-32768 32768];
            app.LevelEditField.ValueDisplayFormat = '%.0f';
            app.LevelEditField.ValueChangedFcn = createCallbackFcn(app, @LevelEditFieldValueChanged, true);
            app.WindowLabel = uilabel(app.viewer4DflowUIFigure);
            app.WindowLabel.Text = 'Window';
            app.TimeFrameLabel = uilabel(app.viewer4DflowUIFigure);
            app.TimeFrameLabel.Text = 'Time Frame';
            app.TimeSlider = uislider(app.viewer4DflowUIFigure);
            app.TimeSlider.Limits = [0 30];
            app.TimeSlider.MajorTicks = [];
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @updateViews, true);
            app.TimeSlider.ValueChangingFcn = createCallbackFcn(app, @TimeSliderValueChanging, true);
            app.TimeSlider.MinorTicks = [];
            app.TimeSlider.Enable = 'off';
            app.WindowEditField = uieditfield(app.viewer4DflowUIFigure, 'numeric');
            app.WindowEditField.Limits = [-32768 32768];
            app.WindowEditField.ValueDisplayFormat = '%.0f';
            app.WindowEditField.ValueChangedFcn = createCallbackFcn(app, @WindowEditFieldValueChanged, true);
            app.LoadDataButton = uibutton(app.viewer4DflowUIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.EncodeDropDownLabel = uilabel(app.viewer4DflowUIFigure);
            app.EncodeDropDownLabel.Text = 'Encode';
            app.EncodeDropDown = uidropdown(app.viewer4DflowUIFigure);
            app.EncodeDropDown.Items = {'Magnitude'};
            app.EncodeDropDown.ValueChangedFcn = createCallbackFcn(app, @EncodeDropDownValueChanged, true);
            app.EncodeDropDown.Value = 'Magnitude';
            app.TimeResButtonGroup = uibuttongroup(app.viewer4DflowUIFigure);
            app.TimeResButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TimeResButtonGroupSelectionChanged, true);
            app.TimeAveragedButton = uiradiobutton(app.TimeResButtonGroup);
            app.TimeAveragedButton.Text = 'Time Averaged';
            app.TimeAveragedButton.Value = true;
            app.TimeResolvedButton = uiradiobutton(app.TimeResButtonGroup);
            app.TimeResolvedButton.Text = 'Time Resolved';
            app.AutoWLButton = uibutton(app.viewer4DflowUIFigure, 'push');
            app.AutoWLButton.ButtonPushedFcn = createCallbackFcn(app, @AutoWLButtonPushed, true);
            app.AutoWLButton.Text = 'Auto W/L';
            app.PlayButton = uibutton(app.viewer4DflowUIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = '▶';
            app.IntervalmsEditFieldLabel = uilabel(app.viewer4DflowUIFigure);
            app.IntervalmsEditFieldLabel.Text = 'Interval (ms)';
            app.IntervalEditField = uieditfield(app.viewer4DflowUIFigure, 'numeric');
            app.IntervalEditField.ValueDisplayFormat = '%.0f';
            app.IntervalEditField.ValueChangedFcn = createCallbackFcn(app, @IntervalEditFieldValueChanged, true);
            app.IntervalEditField.Value = 100;
            app.CoronalLabel = uilabel(app.viewer4DflowUIFigure);
            app.CoronalLabel.Text = '0/0';
            app.AxialLabel = uilabel(app.viewer4DflowUIFigure);
            app.AxialLabel.Text = '0/0';
            app.SagittalLabel = uilabel(app.viewer4DflowUIFigure);
            app.SagittalLabel.Text = '0/0';
            app.TextArea = uitextarea(app.viewer4DflowUIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Figure initialized'};
            app.AxialSlider = uislider(app.viewer4DflowUIFigure);
            app.AxialSlider.Limits = [0 256];
            app.AxialSlider.MajorTicks = [];
            app.AxialSlider.ValueChangingFcn = createCallbackFcn(app, @AxialSliderValueChanging, true);
            app.AxialSlider.MinorTicks = [];
            app.AxialSlider.Enable = 'off';
            app.CoronalSlider = uislider(app.viewer4DflowUIFigure);
            app.CoronalSlider.Limits = [0 256];
            app.CoronalSlider.MajorTicks = [];
            app.CoronalSlider.ValueChangingFcn = createCallbackFcn(app, @CoronalSliderValueChanging, true);
            app.CoronalSlider.MinorTicks = [];
            app.CoronalSlider.Enable = 'off';
            app.SagittalSlider = uislider(app.viewer4DflowUIFigure);
            app.SagittalSlider.Limits = [0 256];
            app.SagittalSlider.MajorTicks = [];
            app.SagittalSlider.ValueChangingFcn = createCallbackFcn(app, @SagittalSliderValueChanging, true);
            app.SagittalSlider.MinorTicks = [];
            app.SagittalSlider.Enable = 'off';
            app.FlipAxial = uibutton(app.viewer4DflowUIFigure, 'push');
            app.FlipAxial.ButtonPushedFcn = createCallbackFcn(app, @FlipAxialButtonPushed, true);
            app.FlipAxial.Text = 'Flip Z';
            app.FlipSagittal = uibutton(app.viewer4DflowUIFigure, 'push');
            app.FlipSagittal.ButtonPushedFcn = createCallbackFcn(app, @FlipSagittalButtonPushed, true);
            app.FlipSagittal.Text = 'Flip X';
            app.FlipCoronal = uibutton(app.viewer4DflowUIFigure, 'push');
            app.FlipCoronal.ButtonPushedFcn = createCallbackFcn(app, @FlipCoronalButtonPushed, true);
            app.FlipCoronal.Text = 'Flip Y';
            app.RotateAxial = uibutton(app.viewer4DflowUIFigure, 'push');
            app.RotateAxial.ButtonPushedFcn = createCallbackFcn(app, @RotateAxialButtonPushed, true);
            app.RotateAxial.Text = 'Rotate Z';
            app.RotateSagittal = uibutton(app.viewer4DflowUIFigure, 'push');
            app.RotateSagittal.ButtonPushedFcn = createCallbackFcn(app, @RotateSagittalButtonPushed, true);
            app.RotateSagittal.Text = 'Rotate X';
            app.RotateCoronal = uibutton(app.viewer4DflowUIFigure, 'push');
            app.RotateCoronal.ButtonPushedFcn = createCallbackFcn(app, @RotateCoronalButtonPushed, true);
            app.RotateCoronal.Text = 'Rotate Y';
            app.ExportGIF = uibutton(app.viewer4DflowUIFigure, 'push');
            app.ExportGIF.ButtonPushedFcn = createCallbackFcn(app, @ExportGIFButtonPushed, true);
            app.ExportGIF.Text = 'Export GIF';
            % Show the figure after all components are created
            app.viewer4DflowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = viewer4Dflow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.viewer4DflowUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.viewer4DflowUIFigure)
        end
    end
end